classdef SIMULATOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinkSimulatorUIFigure     matlab.ui.Figure
        ManualStrideButton        matlab.ui.control.Button
        InverseKinematicsButton   matlab.ui.control.Button
        KalmanFilteringButton     matlab.ui.control.Button
        QLearningButton           matlab.ui.control.Button
        SimulatedAnnealingButton  matlab.ui.control.Button
        WalkingUsingSimulatedAnnealingButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManualStrideButton
        function ManualStrideButtonPushed(app, event)
            StandardStartingParameters
             try
                manualstride
            catch ME
                ME.message
            end
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            StandardStartingParameters
            try
                inversekinematicsdemo
            catch ME
                ME.message
            end
        end

        % Callback function
        function SetPhysicalParametersButtonPushed(app, event)
            setphysicalparameters
        end

        % Button pushed function: QLearningButton
        function QLearningButtonPushed(app, event)
            try
                qlearningsetup
            catch ME
                ME.message
            end
        end

        % Button pushed function: SimulatedAnnealingButton
        function SimulatedAnnealingButtonPushed(app, event)
            try
                simannsetup
            catch ME
                ME.message
            end
        end

        % Button pushed function: KalmanFilteringButton
        function KalmanFilteringButtonPushed(app, event)
            clear s;
            clear sv1;
            clear sv2;
            clear sv3;
            StandardStartingParameters
            try
                kalmanapp
            catch ME
                ME.message
            end
                
        end

        % Button pushed function: 
        % WalkingUsingSimulatedAnnealingButton
        function WalkingUsingSimulatedAnnealingButtonPushed(app, event)
            try
                simannwalksetup
            catch ME
                ME.message
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinkSimulatorUIFigure = uifigure('Visible', 'off');
            app.LinkSimulatorUIFigure.Name = '4 Link Simulator';
            app.ManualStrideButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.ManualStrideButton.ButtonPushedFcn = createCallbackFcn(app, @ManualStrideButtonPushed, true);
            app.ManualStrideButton.Text = 'Manual Stride';
            app.InverseKinematicsButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.KalmanFilteringButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.KalmanFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @KalmanFilteringButtonPushed, true);
            app.KalmanFilteringButton.Text = 'Kalman Filtering';
            app.QLearningButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.QLearningButton.ButtonPushedFcn = createCallbackFcn(app, @QLearningButtonPushed, true);
            app.QLearningButton.Text = 'Q-Learning';
            app.SimulatedAnnealingButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.SimulatedAnnealingButton.ButtonPushedFcn = createCallbackFcn(app, @SimulatedAnnealingButtonPushed, true);
            app.SimulatedAnnealingButton.Text = 'Simulated Annealing';
            app.WalkingUsingSimulatedAnnealingButton = uibutton(app.LinkSimulatorUIFigure, 'push');
            app.WalkingUsingSimulatedAnnealingButton.ButtonPushedFcn = createCallbackFcn(app, @WalkingUsingSimulatedAnnealingButtonPushed, true);
            app.WalkingUsingSimulatedAnnealingButton.Text = 'Walking Using Simulated Annealing';
            % Show the figure after all components are created
            app.LinkSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIMULATOR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinkSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinkSimulatorUIFigure)
        end
    end
end