classdef kalmanapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KalmanSetupUIFigure           matlab.ui.Figure
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        InitialiseServosButton        matlab.ui.control.Button
        CalibrateAccelerometerButton  matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        UseStandardAccelerometerValuesButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseServosButton
        function InitialiseServosButtonPushed(app, event)
            try
                global s;
                global sv1;
                global sv2;
                global sv3;
                [s,sv1,sv2,sv3] = Initialise3servos;
            catch ME
                ME.message
            end
        end

        % Button pushed function: CalibrateAccelerometerButton
        function CalibrateAccelerometerButtonPushed(app, event)
            try
                global calib;
                global s;
                calib = acccalibrate(s);
            catch ME
                ME.message
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            try
                global calib;
                global s;
                global sv1;
                global sv2;
                global sv3;
                kalmanfilterdemo
            catch ME
                ME.message
            end
        end

        % Button pushed function: 
        % UseStandardAccelerometerValuesButton
        function UseStandardAccelerometerValuesButtonPushed(app, event)
            global calib;
            calib = [1.5453;1.2504;1.2928;0.1197;0.2468;0.2542];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KalmanSetupUIFigure = uifigure('Visible', 'off');
            app.KalmanSetupUIFigure.Name = 'Kalman Setup';
            app.Image = uiimage(app.KalmanSetupUIFigure);
            app.Image.ImageSource = 'KalmanSetup.png';
            app.Image2 = uiimage(app.KalmanSetupUIFigure);
            app.Image2.ImageSource = 'Kalmanoffset.jpg';
            app.InitialiseServosButton = uibutton(app.KalmanSetupUIFigure, 'push');
            app.InitialiseServosButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseServosButtonPushed, true);
            app.InitialiseServosButton.Text = 'Initialise Servos';
            app.CalibrateAccelerometerButton = uibutton(app.KalmanSetupUIFigure, 'push');
            app.CalibrateAccelerometerButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateAccelerometerButtonPushed, true);
            app.CalibrateAccelerometerButton.Text = 'Calibrate Accelerometer';
            app.GoButton = uibutton(app.KalmanSetupUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.EditField = uieditfield(app.KalmanSetupUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '(Requires Command Window)';
            app.UseStandardAccelerometerValuesButton = uibutton(app.KalmanSetupUIFigure, 'push');
            app.UseStandardAccelerometerValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UseStandardAccelerometerValuesButtonPushed, true);
            app.UseStandardAccelerometerValuesButton.Text = 'Use Standard Accelerometer Values';
            % Show the figure after all components are created
            app.KalmanSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kalmanapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KalmanSetupUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KalmanSetupUIFigure)
        end
    end
end