classdef qlearningsetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QLearningSetupUIFigure          matlab.ui.Figure
        Phi1EditFieldLabel              matlab.ui.control.Label
        Phi1EditField                   matlab.ui.control.NumericEditField
        Phi2EditFieldLabel              matlab.ui.control.Label
        Phi2EditField                   matlab.ui.control.NumericEditField
        Phi3EditFieldLabel              matlab.ui.control.Label
        Phi3EditField                   matlab.ui.control.NumericEditField
        L1EditFieldLabel                matlab.ui.control.Label
        L1EditField                     matlab.ui.control.NumericEditField
        L2EditFieldLabel                matlab.ui.control.Label
        L2EditField                     matlab.ui.control.NumericEditField
        L3EditFieldLabel                matlab.ui.control.Label
        L3EditField                     matlab.ui.control.NumericEditField
        L4EditFieldLabel                matlab.ui.control.Label
        L4EditField                     matlab.ui.control.NumericEditField
        L5EditFieldLabel                matlab.ui.control.Label
        L5EditField                     matlab.ui.control.NumericEditField
        L6EditFieldLabel                matlab.ui.control.Label
        L6EditField                     matlab.ui.control.NumericEditField
        M1EditFieldLabel                matlab.ui.control.Label
        M1EditField                     matlab.ui.control.NumericEditField
        M2EditFieldLabel                matlab.ui.control.Label
        M2EditField                     matlab.ui.control.NumericEditField
        M3EditFieldLabel                matlab.ui.control.Label
        M3EditField                     matlab.ui.control.NumericEditField
        M4EditFieldLabel                matlab.ui.control.Label
        M4EditField                     matlab.ui.control.NumericEditField
        M5EditFieldLabel                matlab.ui.control.Label
        M5EditField                     matlab.ui.control.NumericEditField
        M6EditFieldLabel                matlab.ui.control.Label
        M6EditField                     matlab.ui.control.NumericEditField
        M7EditFieldLabel                matlab.ui.control.Label
        M7EditField                     matlab.ui.control.NumericEditField
        GoButton                        matlab.ui.control.Button
        DefaultsButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FixedFootButtonGroup            matlab.ui.container.ButtonGroup
        Foot1Button                     matlab.ui.control.ToggleButton
        Foot2Button                     matlab.ui.control.ToggleButton
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        EndRewardEditFieldLabel         matlab.ui.control.Label
        EndRewardEditField              matlab.ui.control.NumericEditField
        PunishmentEditFieldLabel        matlab.ui.control.Label
        PunishmentEditField             matlab.ui.control.NumericEditField
        NumberofAttemptsEditFieldLabel  matlab.ui.control.Label
        NumberofAttemptsEditField       matlab.ui.control.NumericEditField
        StepsperAttemptEditFieldLabel   matlab.ui.control.Label
        StepsperAttemptEditField        matlab.ui.control.NumericEditField
        EndPhi1EditFieldLabel           matlab.ui.control.Label
        EndPhi1EditField                matlab.ui.control.NumericEditField
        EndPhi2EditFieldLabel           matlab.ui.control.Label
        EndPhi2EditField                matlab.ui.control.NumericEditField
        EndPhi3EditFieldLabel           matlab.ui.control.Label
        EndPhi3EditField                matlab.ui.control.NumericEditField
        M8EditFieldLabel                matlab.ui.control.Label
        M8EditField                     matlab.ui.control.NumericEditField
        M9EditFieldLabel                matlab.ui.control.Label
        M9EditField                     matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DefaultsButtonPushed(app)
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            StandardStartingParameters
            startphiVec = [app.Phi1EditField.Value; app.Phi2EditField.Value; app.Phi3EditField.Value];
            endphiVec = [app.EndPhi1EditField.Value; app.EndPhi2EditField.Value; app.EndPhi3EditField.Value];
            lVec = [app.L1EditField.Value; app.L2EditField.Value; app.L3EditField.Value; ...
                app.L4EditField.Value; app.L5EditField.Value; app.L6EditField.Value];
            mVec = [app.M1EditField.Value; app.M2EditField.Value; app.M3EditField.Value; ...
                app.M4EditField.Value; app.M5EditField.Value; app.M6EditField.Value; ...
                app.M7EditField.Value; app.M8EditField.Value; app.M9EditField.Value];
            
            try
                if app.FixedFootButtonGroup.SelectedObject.Text == 'Foot 1'
                    coarsegeneralqlearn1(startphiVec,endphiVec,corners,app.nEditField.Value,app.AlphaEditField.Value...
                        ,app.GammaEditField.Value,app.NumberofAttemptsEditField.Value,app.StepsperAttemptEditField.Value...
                        ,app.EndRewardEditField.Value,app.PunishmentEditField.Value,lVec,mVec,pos1,footsep)
                elseif app.FixedFootButtonGroup.SelectedObject.Text == 'Foot 2'
                    coarsegeneralqlearn2(startphiVec,endphiVec,corners,app.nEditField.Value,app.AlphaEditField.Value...
                        ,app.GammaEditField.Value,app.NumberofAttemptsEditField.Value,app.StepsperAttemptEditField.Value...
                        ,app.EndRewardEditField.Value,app.PunishmentEditField.Value,lVec,mVec,pos2,footsep)
                end
            catch ME
                ME.message
            end
        end

        % Button pushed function: DefaultsButton
        function DefaultsButtonPushed(app, event)
            StandardStartingParameters
            phiVec = [0;0;0];
            app.AlphaEditField.Value = 0.8;
            app.GammaEditField.Value = 0.8;
            app.nEditField.Value = 3;
            app.NumberofAttemptsEditField.Value = 30;
            app.StepsperAttemptEditField.Value = 15;
            app.EndRewardEditField.Value = 5;
            app.PunishmentEditField.Value = -1;
            app.EndPhi1EditField.Value = pi/2;
            app.EndPhi2EditField.Value = 0;
            app.EndPhi3EditField.Value = pi/2;
            
            app.Phi1EditField.Value = phiVec(1);
            app.Phi2EditField.Value = phiVec(2);
            app.Phi3EditField.Value = phiVec(3);
            app.L1EditField.Value = lVec(1);
            app.L2EditField.Value = lVec(2);
            app.L3EditField.Value = lVec(3);
            app.L4EditField.Value = lVec(4);
            app.L5EditField.Value = lVec(5);
            app.L6EditField.Value = lVec(6);
            app.M1EditField.Value = mVec(1);
            app.M2EditField.Value = mVec(2);
            app.M3EditField.Value = mVec(3);
            app.M4EditField.Value = mVec(4);
            app.M5EditField.Value = mVec(5);
            app.M6EditField.Value = mVec(6);
            app.M7EditField.Value = mVec(7);
            app.M8EditField.Value = mVec(8);
            app.M9EditField.Value = mVec(9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QLearningSetupUIFigure = uifigure('Visible', 'off');
            app.QLearningSetupUIFigure.Name = 'Q-Learning Setup';
            app.Phi1EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.Phi1EditFieldLabel.Text = 'Phi1';
            app.Phi1EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.Phi2EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.Phi2EditFieldLabel.Text = 'Phi2';
            app.Phi2EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.Phi3EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.Phi3EditFieldLabel.Text = 'Phi3';
            app.Phi3EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L1EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L1EditField.Value = 1.2;
            app.L2EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L2EditField.Value = 1.4;
            app.L3EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L3EditField.Value = 1.4;
            app.L4EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L4EditField.Value = 1.2;
            app.L5EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L5EditFieldLabel.Text = 'L5';
            app.L5EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L5EditField.Value = 1.5;
            app.L6EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.L6EditFieldLabel.Text = 'L6';
            app.L6EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.L6EditField.Value = 1.5;
            app.M1EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M1EditFieldLabel.Text = 'M1';
            app.M1EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M1EditField.Value = 1;
            app.M2EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M2EditFieldLabel.Text = 'M2';
            app.M2EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M2EditField.Value = 1;
            app.M3EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M3EditFieldLabel.Text = 'M3';
            app.M3EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M3EditField.Value = 1;
            app.M4EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M4EditFieldLabel.Text = 'M4';
            app.M4EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M4EditField.Value = 1;
            app.M5EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M5EditFieldLabel.Text = 'M5';
            app.M5EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M5EditField.Value = 1;
            app.M6EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M6EditFieldLabel.Text = 'M6';
            app.M6EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M6EditField.Value = 1;
            app.M7EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M7EditFieldLabel.Text = 'M7';
            app.M7EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M7EditField.Value = 1;
            app.GoButton = uibutton(app.QLearningSetupUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.DefaultsButton = uibutton(app.QLearningSetupUIFigure, 'push');
            app.DefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultsButtonPushed, true);
            app.DefaultsButton.Text = 'Defaults';
            app.Image = uiimage(app.QLearningSetupUIFigure);
            app.Image.ImageSource = 'Labels.png';
            app.FixedFootButtonGroup = uibuttongroup(app.QLearningSetupUIFigure);
            app.FixedFootButtonGroup.Title = 'Fixed Foot';
            app.Foot1Button = uitogglebutton(app.FixedFootButtonGroup);
            app.Foot1Button.Text = 'Foot 1';
            app.Foot1Button.Value = true;
            app.Foot2Button = uitogglebutton(app.FixedFootButtonGroup);
            app.Foot2Button.Text = 'Foot 2';
            app.AlphaEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.AlphaEditField.Value = 0.8;
            app.GammaEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.GammaEditField.Value = 0.8;
            app.nEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.nEditField.Value = 3;
            app.EndRewardEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.EndRewardEditFieldLabel.Text = 'End Reward';
            app.EndRewardEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.EndRewardEditField.Value = 5;
            app.PunishmentEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.PunishmentEditFieldLabel.Text = 'Punishment';
            app.PunishmentEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.PunishmentEditField.Value = -1;
            app.NumberofAttemptsEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.NumberofAttemptsEditFieldLabel.Text = 'Number of Attempts';
            app.NumberofAttemptsEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.NumberofAttemptsEditField.Value = 30;
            app.StepsperAttemptEditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.StepsperAttemptEditFieldLabel.Text = 'Steps per Attempt';
            app.StepsperAttemptEditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.StepsperAttemptEditField.Value = 15;
            app.EndPhi1EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.EndPhi1EditFieldLabel.Text = 'End Phi1';
            app.EndPhi1EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.EndPhi1EditField.Value = 1.5707963267949;
            app.EndPhi2EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.EndPhi2EditFieldLabel.Text = 'End Phi2';
            app.EndPhi2EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.EndPhi3EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.EndPhi3EditFieldLabel.Text = 'End Phi3';
            app.EndPhi3EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.EndPhi3EditField.Value = 1.5707963267949;
            app.M8EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M8EditFieldLabel.Text = 'M8';
            app.M8EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M8EditField.Value = 1;
            app.M9EditFieldLabel = uilabel(app.QLearningSetupUIFigure);
            app.M9EditFieldLabel.Text = 'M9';
            app.M9EditField = uieditfield(app.QLearningSetupUIFigure, 'numeric');
            app.M9EditField.Value = 1;
            app.TextArea = uitextarea(app.QLearningSetupUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Note: Obstacles can be added by editing the ''corners'' variable in StandardStartingParameters.m'};
            % Show the figure after all components are created
            app.QLearningSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qlearningsetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QLearningSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QLearningSetupUIFigure)
        end
    end
end